classdef Guitar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                    % UI Figure
        Button                 matlab.ui.control.Button           
        Button2                matlab.ui.control.Button           
        Fuzz_effect            matlab.ui.container.Panel           % Fuzz
        fuzz_on                matlab.ui.control.StateButton       % On
        LabelKnob              matlab.ui.control.Label             % Fuzz
        Knob_fuzz_gain         matlab.ui.control.Knob              % [0 10]
        Panel2                 matlab.ui.container.Panel           % Tuner
        LabelSemicircularGauge matlab.ui.control.Label             % Semici...
        SemicircularGauge      matlab.ui.control.SemicircularGauge % [-20 20]
        EditField              matlab.ui.control.EditField        
        tuner_on               matlab.ui.control.StateButton       % On
        Comp_effect            matlab.ui.container.Panel           % Compress
        LabelKnob2             matlab.ui.control.Label             % comp
        Knob_comp_comp         matlab.ui.control.Knob              % [-1 1]
        LabelKnob3             matlab.ui.control.Label             % filter
        Knob_comp_filter       matlab.ui.control.Knob              % [0 1]
        comp_on                matlab.ui.control.StateButton       % On
        Panel3                 matlab.ui.container.Panel           % AutoWAH
        LabelKnob4             matlab.ui.control.Label             % Speed
        Knob                   matlab.ui.control.Knob              % [0 10]
        Statebutton            matlab.ui.control.StateButton       % On
    end

    
    properties (Access = private)
        deviceReader = audioDeviceReader('Device', 'VOX 2ch Audio Device', 'SampleRate', 10000 , 'SamplesPerFrame',5000);
        deviceWriter = audioDeviceWriter;
        run double
        dist double
        t = timer
        
        %Fuzz properties
        fuzz_gain
        fuzz_mix
        fuzz_pass
        
        %Compressor properties
        compress
        comp_filter
        comp_pass
        
        %wah properties
        wah_damp
        wah_minf
        wah_maxf
        wah_Fw
        wah_pass
        
        %Tuner properties
        tuner_pass
    end
    
    methods (Access = private)
        
        function results = get_dist(app)
            results = 2*app.dist/(1-app.dist);
        end
        
        function results = process(app, signal)
            results = (1+get_dist(app))*(signal)./(1+get_dist(app)*abs(signal));
            %               results = (1+get_dist(app)).*(signal);
        end
        
        function results = is_run(app)
            results = app.run;
        end
        
        function results = fuzzexp(app, x, gain, mix)
            q=x*gain/max(abs(x));
            z=sign(-q).*(1-exp(sign(-q).*q));
            results=mix*z*max(abs(x))/max(abs(z))+(1-mix)*x;
            results=results*max(abs(x))/max(abs(results));
        end
        
        function results = compexp(app, x,comp,a,Fs)
            h=filter([(1-a)^2],[1.0000 -2*a a^2],abs(x));
            h=h/max(h);
            h=h.^comp;
            
            results=x.*h;
            
            results= results*max(abs(x))/max(abs(results));
            
        end

        function results = wah(app, x, damp, minf, maxf, Fw, Fs)
            % change in centre frequency per sample (Hz)
            %delta=0.1;
            delta = Fw/Fs;
            %0.1 => at 44100 samples per second should mean  4.41kHz Fc shift per sec
            
               
            % create triangle wave of centre frequency values
            Fc=minf:delta:maxf;
            while(length(Fc) < length(x) )
                Fc= [ Fc (maxf:-delta:minf) ];
                Fc= [ Fc (minf:delta:maxf) ];
            end
            
            % trim tri wave to size of input
            Fc = Fc(1:length(x));
            
            
            % difference equation coefficients
            F1 = 2*sin((pi*Fc(1))/Fs);  % must be recalculated each time Fc changes
            Q1 = 2*damp;                % this dictates size of the pass bands
            
            
            yh=zeros(size(x));          % create emptly out vectors
            results=zeros(size(x));
            yl=zeros(size(x));
            
            % first sample, to avoid referencing of negative signals
            yh(1) = x(1);
            results(1) = F1*yh(1);
            yl(1) = F1*results(1);
                        
            % apply difference equation to the sample
            for n=2:length(x),
                
                yh(n) = x(n) - yl(n-1) - Q1*results(n-1);
                results(n) = F1*yh(n) + results(n-1);
                yl(n) = F1*results(n) + yl(n-1);
                                
                F1 = 2*sin((pi*Fc(n))/Fs);
            end
            
            %normalise
            
            maxresults = max(abs(results));
            results = results/maxresults;
        end

        function results = tuner(app,x, Fs)
            E_2 = 82.41;
            A_2 = 110.00;
            D_3 = 146.80;
            G_3 = 196.00;
            B_3 = 246.90;
            E_4 = 329.62;
            six_strings = [E_2, A_2, D_3, G_3, B_3, E_4];
            note = {'E2','A2','D3','G3','B3','E4'};
            
            % record input signal
%             fs = 44100;
%             recObj = audiorecorder(fs, 16, 1);
%             disp('Start recording for 3 secs.')
%             recordblocking(recObj, 3);
%             disp('End of recording.');
%             input_signal = getaudiodata(recObj);
            
            % get freq by prof. Chang's method
%             audiowrite('./temp.wav', input_signal, fs);
%             auFile='./temp.wav';
%             au=myAudioRead(auFile);
            au.signal = x;
            au.fs = Fs;
            au.nbits = 16;
            au.amplitudeNormalized=1;
            ptOpt=ptOptSet(Fs, 16);
            pitch=pitchTrack(au, ptOpt, 0);
            freq = pitch2freq(pitch);
            input_freq = 0;
            cnt = 0;
            for i = 1:size(pitch, 2)
                if freq(i) ~= 0
                    input_freq = input_freq + freq(i);
                    cnt = cnt + 1;
                end
            end
            input_freq = input_freq / cnt;
            
            % get nearest String
            temp = six_strings-input_freq;
            [c ,index] = min(abs(temp));
            fprintf (' %f index.\n',index);
            target_freq = six_strings(index); % Finds first one only!
            % define epsilon
            r_bound = (1.0030 * target_freq);
            l_bound = (0.9970 * target_freq);
            GUI_index = 0;
            % result
            if input_freq > l_bound && input_freq < r_bound
                display ('Perfect Intonation!!!');
                fprintf ('The frequency of the input signal is %f Hz.\n', input_freq);
                GUI_index = 0;
            
            elseif input_freq < target_freq 
                display ('Input frequency should be increased');
                fprintf ('The frequency of the input signal is %f Hz.\n',input_freq);
                GUI_index = input_freq - target_freq;
                if GUI_index < -20
                    GUI_index = -20;
                end
            
            else input_freq > target_freq
                display ('Input frequency should be decreased');
                fprintf ('The frequency of the input signal is %f Hz.\n',input_freq);
                GUI_index = input_freq - target_freq;
                if GUI_index > 20
                    GUI_index = 20;
                end
            end
            if index ==0
                results.note = '0';
            else
                results.note = note{index};
            end
            results.GUI_index = GUI_index;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.run=0;
            app.dist = 0.5;
            
            %Fuzz init
            app.fuzz_gain=0;
            app.fuzz_mix=1;
            app.fuzz_pass=false;
            
            %Compressor init
            app.compress = -0.5;
            app.comp_filter = 0.5;
            app.comp_pass = false;
            
            %wah init
            app.wah_damp = 0.02;
            app.wah_minf = 300;
            app.wah_maxf = 3000;
            app.wah_Fw = 3000;
            app.wah_pass = false;
            
            %Tuner init
            app.tuner_pass = false;
        end

        % Button button pushed function
        function start_eff(app)
            if app.tuner_pass ==true
                app.deviceReader = audioDeviceReader('Device', 'VOX 2ch Audio Device', 'SampleRate', 10000 , 'SamplesPerFrame',10000);
            elseif app.wah_pass == true
                app.deviceReader = audioDeviceReader('Device', 'VOX 2ch Audio Device', 'SampleRate', 10000 , 'SamplesPerFrame',4000);
            else
                app.deviceReader = audioDeviceReader('Device', 'VOX 2ch Audio Device', 'SampleRate', 10000 , 'SamplesPerFrame',32);
            end
            app.deviceWriter = audioDeviceWriter('SampleRate',app.deviceReader.SampleRate);
            app.Button.Enable = 'off';
            app.run=1;
            tic
            while app.run==1
                mySignal = record(app.deviceReader);
%                 mySignal = [mySignal mySignal];
                %wah-wah
                if app.tuner_pass == true
                    results = tuner(app, mySignal, app.deviceReader.SampleRate);
                    app.EditField.Value = results.note;
                    if strcmp(results.note, '0')
                        app.EditField.BackgroundColor = [0.96,0.96,0.96];
                    elseif results.GUI_index ==0
                        app.EditField.BackgroundColor = [0.50,1.00,0.00];
                        app.SemicircularGauge.Value = results.GUI_index;
                    else
                        app.EditField.BackgroundColor = [0.96,0.96,0.96];
                        app.SemicircularGauge.Value = results.GUI_index;
                    end
                    
                end
                if app.wah_pass ==true
                    mySignal = wah(app, mySignal, app.wah_damp, app.wah_minf, app.wah_maxf, app.wah_Fw, app.deviceReader.SampleRate);
                end
                %Compressor
                if app.comp_pass == true
                    mySignal = compexp(app, mySignal, app.compress, app.comp_filter, app.deviceReader.SampleRate);
                end
                
                %Fuzz
                if app.fuzz_pass == true
                    mySignal = fuzzexp(app, mySignal, app.fuzz_gain, app.fuzz_mix);
                end
%                 mySignal = [mySignal mySignal];
                play(app.deviceWriter, mySignal);
                %                  curval = get(app.t, 'UserData');
                pause(0.0000001);
            end
            app.Button.Enable = 'on';
            release(app.deviceReader);
            release(app.deviceWriter);
        end

        % Button2 button pushed function
        function Stop_run(app)

            app.run=0;
        end

        % Knob_fuzz_gain value changed function
        function fuzz_gain_knob(app)
            app.fuzz_gain = app.Knob_fuzz_gain.Value;
            
        end

        % comp_on value changed function
        function comp_on_button(app)
            if strcmp(app.comp_on.Text , 'On')
              app.comp_on.Text = 'Off';
              app.comp_pass = app.comp_on.Value;
            else
              app.comp_on.Text = 'On';
              app.comp_pass = app.comp_on.Value;
            end
        end

        % fuzz_on value changed function
        function fuzz_on_button(app)
            if strcmp(app.fuzz_on.Text , 'On')
              app.fuzz_on.Text = 'Off';
              app.fuzz_pass = app.fuzz_on.Value;
            else
              app.fuzz_on.Text = 'On';
              app.fuzz_pass = app.fuzz_on.Value;
            end
            
        end

        % Knob_comp_comp value changed function
        function comp_comp_knob(app)
            app.compress = app.Knob_comp_comp.Value;
            
        end

        % Knob_comp_filter value changed function
        function comp_filter_knob(app)
            app.comp_filter = app.Knob_comp_filter.Value;
            
        end

        % Knob value changed function
        function wah_speed_knob(app)
            app.wah_Fw = app.Knob.Value*(10000-3000)/10+3000;
         
        end

        % Statebutton value changed function
        function wah_on_button(app)
            if strcmp(app.Statebutton.Text , 'On')
              app.Statebutton.Text = 'Off';
              app.wah_pass = app.Statebutton.Value;
            else
              app.Statebutton.Text = 'On';
              app.wah_pass = app.Statebutton.Value;
            end
            
        end

        % tuner_on value changed function
        function tuner_on_button(app)
            if strcmp(app.tuner_on.Text , 'On')
              app.tuner_on.Text = 'Off';
              app.tuner_pass = app.tuner_on.Value;
            else
              app.tuner_on.Text = 'On';
              app.tuner_pass = app.tuner_on.Value;
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @start_eff);
            app.Button.Icon = 'Screen Shot 2016-06-20 at 100.jpg';
            app.Button.Text = '';
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Stop_run);
            app.Button2.Icon = 'Screen Shot 2016-06-20 at 101.jpg';
            app.Button2.Text = '';
            app.Fuzz_effect = uipanel(app.UIFigure);
            app.Fuzz_effect.Title = 'Fuzz';
            app.Fuzz_effect.Units = 'pixels';
            app.fuzz_on = uibutton(app.Fuzz_effect, 'state');
            app.fuzz_on.ValueChangedFcn = createCallbackFcn(app, @fuzz_on_button);
            app.fuzz_on.Text = 'On';
            app.LabelKnob = uilabel(app.Fuzz_effect);
            app.LabelKnob.Text = 'Fuzz';
            app.Knob_fuzz_gain = uiknob(app.Fuzz_effect, 'continuous');
            app.Knob_fuzz_gain.Limits = [0 10];
            app.Knob_fuzz_gain.ValueChangedFcn = createCallbackFcn(app, @fuzz_gain_knob);
            app.Knob_fuzz_gain.Value = 7;
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Tuner';
            app.Panel2.Units = 'pixels';
            app.LabelSemicircularGauge = uilabel(app.Panel2);
            app.LabelSemicircularGauge.Text = 'Semicircular Gauge';
            app.SemicircularGauge = uigauge(app.Panel2, 'semicircular');
            app.SemicircularGauge.Limits = [-20 20];
            app.EditField = uieditfield(app.Panel2, 'text');
            app.EditField.Editable = 'off';
            app.tuner_on = uibutton(app.Panel2, 'state');
            app.tuner_on.ValueChangedFcn = createCallbackFcn(app, @tuner_on_button);
            app.tuner_on.Text = 'On';
            app.Comp_effect = uipanel(app.UIFigure);
            app.Comp_effect.Title = 'Compress';
            app.Comp_effect.Units = 'pixels';
            app.LabelKnob2 = uilabel(app.Comp_effect);
            app.LabelKnob2.Text = 'comp';
            app.Knob_comp_comp = uiknob(app.Comp_effect, 'continuous');
            app.Knob_comp_comp.Limits = [-1 1];
            app.Knob_comp_comp.MajorTicks = [-1 -0.6 -0.2 0.2 0.6 1];
            app.Knob_comp_comp.ValueChangedFcn = createCallbackFcn(app, @comp_comp_knob);
            app.LabelKnob3 = uilabel(app.Comp_effect);
            app.LabelKnob3.Text = 'filter';
            app.Knob_comp_filter = uiknob(app.Comp_effect, 'continuous');
            app.Knob_comp_filter.Limits = [0 1];
            app.Knob_comp_filter.ValueChangedFcn = createCallbackFcn(app, @comp_filter_knob);
            app.comp_on = uibutton(app.Comp_effect, 'state');
            app.comp_on.ValueChangedFcn = createCallbackFcn(app, @comp_on_button);
            app.comp_on.Text = 'On';
            app.Panel3 = uipanel(app.UIFigure);
            app.Panel3.Title = 'AutoWAH';
            app.Panel3.Units = 'pixels';
            app.LabelKnob4 = uilabel(app.Panel3);
            app.LabelKnob4.Text = 'Speed';
            app.Knob = uiknob(app.Panel3, 'continuous');
            app.Knob.Limits = [0 10];
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @wah_speed_knob);
            app.Statebutton = uibutton(app.Panel3, 'state');
            app.Statebutton.ValueChangedFcn = createCallbackFcn(app, @wah_on_button);
            app.Statebutton.Text = 'On';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Guitar()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end